classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        wykresyButton                 matlab.ui.control.Button
        UzupelnijzeramiDropDown       matlab.ui.control.DropDown
        UzupelnijzeramiDropDownLabel  matlab.ui.control.Label
        skalaDropDown                 matlab.ui.control.DropDown
        skalaDropDownLabel            matlab.ui.control.Label
        parametrEditField             matlab.ui.control.NumericEditField
        parametrEditFieldLabel        matlab.ui.control.Label
        OknoListBox                   matlab.ui.control.ListBox
        OknoLabel                     matlab.ui.control.Label
        fpEditField                   matlab.ui.control.EditField
        fpEditFieldLabel              matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        Tab3                          matlab.ui.container.Tab
        UIAxes6                       matlab.ui.control.UIAxes
        Tab4                          matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        PrzestrzenroboczaEditField    matlab.ui.control.EditField
        PrzestrzenroboczaEditFieldLabel  matlab.ui.control.Label
        WczytajButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        sig % sygnal probki
        sig_amp
        sig_phase
        fp = 0
        time_vector
        freq_vector
        beta = 100
        window = 0
        skala = 1
        d = 0

        callerApp % Description
        p % Description
        
        % p=sin(pi*(0:256-1)/256*4);
    end

    methods (Access = public)
        
        function p = getp(app)
            p = app.sig;
        end
% 
%         function f = getf(app)
%             f = app.freq_vector;
%         end
% 
%         function z = getz(app)
%             z = app.d;
%         end
    end

    methods (Access = private)

        function settime_and_freq(app)
            N = length(app.sig);
            if app.fp > 0
                app.time_vector = (0:N-1)/app.fp;
                app.freq_vector = linspace(0,app.fp,app.d);
            else
                app.time_vector = (0:N-1);
                app.freq_vector = linspace(0,(N-1),app.d);
            end
        end

        function draw_win(app)
            plot(app.UIAxes,app.time_vector, app.sig);
            if app.skala == 1
                plot(app.UIAxes6, app.freq_vector, app.sig_amp)
            else
                plot(app.UIAxes6, app.freq_vector, 20*log10(app.sig_amp));
            end
            stem(app.UIAxes3,app.freq_vector, app.sig_phase);
        end

        function UZerami(app)

            if 2^nextpow2(length(app.sig)) == length(app.sig)
                potega = 2^(nextpow2(length(app.sig))+1);
            else
                potega = 2^nextpow2(length(app.sig));
            end

            zera = cell(1,9);
            zera{1} = 'Wylacz';

            for i = 2:9
                zera{i} = num2str(potega);
                potega = potega*2;

            end
            app.UzupelnijzeramiDropDown.Items = zera;
        end
        
        function makeFFT(app)
            x = ((app.sig.*app.window)')/sum(app.window);
            fftx = fft(x,app.d);
            app.sig_amp = abs(fftx);
            app.sig_phase = angle(fftx)*180/pi;
        end
        
        function makeWindow(app)
            value = app.OknoListBox.Value;
            N = length(app.sig);

            if value == "Prostokatne"
                app.window = rectwin(N+1)';
                app.window(N+1) = [];
            elseif value == "Trojkatne"
                app.window = bartlett(N+1)';
                app.window(N+1) = [];
            elseif value == "Hanninga"
                app.window = hanning(N,"periodic")';
            elseif value == "Hamminga"
                app.window = hamming(N,"periodic")';
            elseif value == "Blackmana"
                app.window = blackman(N,"periodic")';
            elseif value == "Kaiser"
                app.window = kaiser(N+1,app.beta)';
                app.window(N+1) = [];
            elseif value == "D-C"
                app.window = chebwin(N+1,app.beta)';
                app.window(N+1) = [];
            elseif value == "sin^p"
                app.window = (sin(pi*(0:N-1)/N).^app.beta);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.p=app.PrzestrzenroboczaEditField.Value;
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.skala = 1;
            app.window = 0;
            [file,path] = uigetfile;
            if file ~=0
                load([path,file],'x');
                app.sig = x;
                app.d = length(x);
                app.window = rectwin(length(app.sig))';
                app.makeFFT();
                app.settime_and_freq();
                %usun value z pola fp, zmien zeowamie na wyl\cz
                app.draw_win();
                app.UZerami();
                figure(app.UIFigure)
            end
        end

        % Value changed function: PrzestrzenroboczaEditField
        function PrzestrzenroboczaEditFieldValueChanged(app, event)
            value = app.PrzestrzenroboczaEditField.Value;
            app.skala = 1;
            app.window = 0;
            if value~=""
                app.sig = evalin("base",value);
                app.d = length(app.sig);
                app.window = rectwin(length(app.sig))';
                app.makeFFT();
                app.settime_and_freq();
                app.draw_win();
                app.UZerami();
            end
        end

        % Value changed function: fpEditField
        function fpEditFieldValueChanged(app, event)
            value = str2double(app.fpEditField.Value);

            app.fp = value;
            app.settime_and_freq();
            app.draw_win();

        end

        % Value changed function: skalaDropDown
        function skalaDropDownValueChanged(app, event)
            value = app.skalaDropDown.Value;
            cla(app.UIAxes6);
            if value == "decybelowa"
                app.skala = 0;
            else
                app.skala = 1;
            end

            draw_win(app);

        end

        % Value changed function: OknoListBox
        function OknoListBoxValueChanged(app, event)
            makeWindow(app);
            app.makeFFT();
            draw_win(app);

        end

        % Value changed function: parametrEditField
        function parametrEditFieldValueChanged(app, event)
            value = app.parametrEditField.Value;
            app.beta = value;
            makeWindow(app);
            makeFFT(app);
            draw_win(app);
        end

        % Value changed function: UzupelnijzeramiDropDown
        function UzupelnijzeramiDropDownValueChanged(app, event)
            value = app.UzupelnijzeramiDropDown.Value;
            if value == "Wylacz"
                app.d = length(app.sig);
            else
                app.d = str2double(value);
            end
            settime_and_freq(app);
            app.makeFFT();
            draw_win(app);
        end

        % Button pushed function: wykresyButton
        function wykresyButtonPushed(app, event)
        app.callerApp = caller(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.PrzestrzenroboczaEditFieldLabel = uilabel(app.UIFigure);
            app.PrzestrzenroboczaEditFieldLabel.Text = {'Przestrzen robocza'; ''};
            app.PrzestrzenroboczaEditField = uieditfield(app.UIFigure, 'text');
            app.PrzestrzenroboczaEditField.ValueChangedFcn = createCallbackFcn(app, @PrzestrzenroboczaEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Sygnal')
            xlabel(app.UIAxes, 'Czas [s]')
            ylabel(app.UIAxes, 'Amplituda ')
            zlabel(app.UIAxes, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes6 = uiaxes(app.Tab3);
            title(app.UIAxes6, 'Widmo amplitudowe')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes3 = uiaxes(app.Tab4);
            title(app.UIAxes3, 'Widmo fazowe')
            xlabel(app.UIAxes3, 'f [Hz]')
            ylabel(app.UIAxes3, 'A')
            zlabel(app.UIAxes3, 'Z')
            app.fpEditFieldLabel = uilabel(app.UIFigure);
            app.fpEditFieldLabel.Text = 'fp';
            app.fpEditField = uieditfield(app.UIFigure, 'text');
            app.fpEditField.ValueChangedFcn = createCallbackFcn(app, @fpEditFieldValueChanged, true);
            app.OknoLabel = uilabel(app.UIFigure);
            app.OknoLabel.Text = {'Okno'; ''};
            app.OknoListBox = uilistbox(app.UIFigure);
            app.OknoListBox.Items = {'Prostokatne', 'Trojkatne', 'Hanninga', 'Hamminga', 'Blackmana', 'Kaiser', 'D-C', 'sin^p'};
            app.OknoListBox.ValueChangedFcn = createCallbackFcn(app, @OknoListBoxValueChanged, true);
            app.OknoListBox.Value = 'Prostokatne';
            app.parametrEditFieldLabel = uilabel(app.UIFigure);
            app.parametrEditFieldLabel.Text = 'parametr';
            app.parametrEditField = uieditfield(app.UIFigure, 'numeric');
            app.parametrEditField.ValueChangedFcn = createCallbackFcn(app, @parametrEditFieldValueChanged, true);
            app.parametrEditField.Value = 100;
            app.skalaDropDownLabel = uilabel(app.UIFigure);
            app.skalaDropDownLabel.Text = 'skala';
            app.skalaDropDown = uidropdown(app.UIFigure);
            app.skalaDropDown.Items = {'liniowa', 'decybelowa'};
            app.skalaDropDown.ValueChangedFcn = createCallbackFcn(app, @skalaDropDownValueChanged, true);
            app.skalaDropDown.Value = 'liniowa';
            app.UzupelnijzeramiDropDownLabel = uilabel(app.UIFigure);
            app.UzupelnijzeramiDropDownLabel.Text = 'Uzupelnij zerami';
            app.UzupelnijzeramiDropDown = uidropdown(app.UIFigure);
            app.UzupelnijzeramiDropDown.Items = {};
            app.UzupelnijzeramiDropDown.ValueChangedFcn = createCallbackFcn(app, @UzupelnijzeramiDropDownValueChanged, true);
            app.UzupelnijzeramiDropDown.Value = {};
            app.wykresyButton = uibutton(app.UIFigure, 'push');
            app.wykresyButton.ButtonPushedFcn = createCallbackFcn(app, @wykresyButtonPushed, true);
            app.wykresyButton.Text = 'wykresy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end